classdef cellAreaRICM_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        rightFrame          matlab.ui.control.Button
        leftFrame           matlab.ui.control.Button
        uipanel1            matlab.ui.container.Panel
        processAll          matlab.ui.control.Button
        text18              matlab.ui.control.Label
        text16              matlab.ui.control.Label
        minSize             matlab.ui.control.EditField
        blur                matlab.ui.control.EditField
        simpleThresholdBox  matlab.ui.container.ButtonGroup
        text10              matlab.ui.control.Label
        lowVal              matlab.ui.control.EditField
        text14              matlab.ui.control.Label
        text11              matlab.ui.control.Label
        stdVal              matlab.ui.control.EditField
        highVal             matlab.ui.control.EditField
        text19              matlab.ui.control.Label
        preview             matlab.ui.control.CheckBox
        frameText           matlab.ui.control.Label
        saveData            matlab.ui.control.Button
        uibuttongroup3      matlab.ui.container.ButtonGroup
        deleteMult          matlab.ui.control.Button
        deletePoint         matlab.ui.control.Button
        areaList            matlab.ui.control.ListBox
        areaGraph           matlab.ui.control.UIAxes
        uibuttongroup2      matlab.ui.container.ButtonGroup
        analyzeByHand       matlab.ui.control.StateButton
        text10_2            matlab.ui.control.Label
        crop                matlab.ui.control.Button
        subBackground       matlab.ui.control.Button
        setEnd              matlab.ui.control.Button
        setStart            matlab.ui.control.Button
        videoSlider         matlab.ui.control.Slider
        cellVideo           matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function handles = automatedAnalysis(app, handles, display, save)
            % compare pictures before and after! (can't change too much)
            handles.display = display; % to feed into saveArea function
            axes(handles.cellVideo)
            logic = handles.areaFrames == handles.frameNum;
            overwrite = false;
            if display % if not to display, automatically begin analyzing
                if any(logic) % if it has been analyzed before
                    if save % if save is true, function will overwrite previous value, otherwise just a preview
                        overwrite = true;
                    end
                end
            else
                if any(logic)
                    overwrite = true;
                end
            end
            if overwrite
                num = find(logic);
                L = length(handles.area);
                index = horzcat(1:(num-1),(num+1):L);
                % get rid of the past analysis for this frame (this is a redo)
                handles.area = handles.area(index);
                handles.areaFrames = handles.areaFrames(index);
                handles.handDrawn = handles.handDrawn(index);
                handles.areaTime = handles.areaTime(index);
                handles.areaPolys = handles.areaPolys(index);
                handles.cellPrints = handles.cellPrints(:,:,index);
                % find position of this area in the string list (to delete)
                testIdx = 1;
                while sum(handles.isArea(1:testIdx)) < num
                    testIdx = testIdx + 1;
                end
                N = length(handles.stringCell);
                index2 = horzcat(1:(testIdx-1),(testIdx+1):N);
                handles.stringCell = handles.stringCell(index2);
                handles.stringFrames = handles.stringFrames(index2);
                handles.isArea = handles.isArea(index2);
                handles.stringTime = handles.stringTime(index2);
                % reset area list strings to all excluding current being analyzed
                set(handles.areaList,'Value',1)
                set(handles.areaList,'String',handles.stringCell)
                try
                    set(handles.patch,'Visible','off')
                catch % no action
                end
            end
            if ~handles.classified
                threshIm = segmentationRICM(handles); % segmentation algorithm
            else % load segmented images from classifier
                if handles.ilastikKeep(handles.frameNum)
                    im = imread(fullfile(handles.ilastikPath,handles.ilastikFiles{handles.frameNum}));
                    threshIm = im == 1;
                else
                    save = false;
                    threshIm = zeros(size(handles.currentImage));
                end
            end
            minSize = str2double(get(handles.minSize, 'String'));
            blur = str2double(get(handles.blur, 'String'));
            [outline, threshIm] = tracesFromBinary(threshIm, minSize, blur); % outline body
            handles.curPrint = threshIm;
            xpos = outline(:,2);
            ypos = outline(:,1);
            if display
                if get(handles.preview, 'Value') % draw preview in red
                    handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','red','FaceColor','none');
                else
                    handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none');
                end
            end
            if save
                handles = saveArea(app, xpos, ypos, false, handles);
            else %if only a preview (no saving), display value on graph
                area = polyarea(xpos,ypos)*(handles.pxScale^2);
                if display
                    axes(handles.areaGraph)
                    cla
                    plot(handles.areaTime, handles.area)
                    if max(handles.area) < 300
                        ylim([0 300])
                    end
                    xlabel('Time (s)')
                    ylabel(sprintf('Area (%s)', handles.pxScaleUnit))
                    hold on
                    plot(handles.time(handles.frameNum), area, 'Marker', '*', 'MarkerEdgeColor', 'r', 'MarkerSize', 6)
                    hold off
                end
            end
        end
        
        function handles = initializeSeq(app, handles)
            if ~isfield(handles, 'mainPath') % if not given a main path yet
                path = 0;
                ext = '';
                while any(path==0) || (~strcmp(ext, '.bmp') && ~strcmp(ext, '.tiff')) % needs to be an image file
                    [currentFile,path] = uigetfile({'*.*'},'Pick first file of image sequence');
                    [~, ~, ext] = fileparts(currentFile);
                end
                handles.fileType = ext(2:end);
                handles.mainPath = path;
            end
            % read in file names
            cd(handles.mainPath)
            handles.sequence = dir(strcat('*.', handles.fileType));
            handles.files = {handles.sequence.name};
            handles.l = length(handles.files);
            handles.currentImage = double(imread(handles.files{1}));
            if isfield(handles, 'subtracted')
                if handles.subtracted
                    handles.currentImage = (handles.currentImage - mean(handles.currentImage(:))) - handles.BG;
                end
            else
                handles.subtracted = false;
            end
            axes(handles.cellVideo)
            imagesc(handles.currentImage);
            daspect([1 1 1])
            colormap gray; %grayscale images
            handles.frameNum = 1;
            set(handles.videoSlider,'SliderStep',[1/(handles.l-1) 10/(handles.l-1)]); %initialize video slider (moves 1 frame per click on arrow, 10 frames per click next to bar)
            xL = get(gca,'XLim');
            yL = get(gca,'YLim');
            handles.cropPos = [xL(1),yL(1); xL(1),yL(2); xL(2),yL(2); xL(2),yL(1)]; %cropped poly initialized to entire image
            handles.cropped = false;
            % Now, find time data to go with image sequence
            file = 0;
            textFiles = dir('*.txt');
            textFiles = {textFiles.name};
            fileRead = false;
            detected = false;
            refFirst = false;
            for i = 1:length(textFiles)
                if ~isempty(strfind(textFiles{i}, '_andor_times_pressures.txt')) || ~isempty(strfind(textFiles{i}, '_ueye_times_pressures.txt')) || ~isempty(strfind(textFiles{i}, '_timesonly.txt'))
                    file = textFiles{i};
                    path = handles.mainPath;
                    if ~isempty(strfind(textFiles{i}, '_timesonly.txt'))
                        refFirst = true;
                    end
                    detected = true;
                    break;
                end
            end
            while ~fileRead
                if ~detected
                    [file,path] = uigetfile('*.txt','Pick time file');
                end
                if file ~= 0
                    try timepressure = load(fullfile(path,file)); % for two column time pressure data
                        time = timepressure(:,1).';
                        if refFirst
                            handles.rawtime = time(2:end);
                            handles.time = (handles.rawtime - time(1)) / 1000;
                        else
                            handles.rawtime = time;
                            handles.time = (time-time(1))/1000; %originally in ms
                        end
                        fileRead = true;
                    catch % for log file formatted in Volkmar's program
                        fid = fopen(fullfile(path,file),'r');
                        timepressure = textscan(fid,'%s %s %s %f %s %s %f','Delimiter','\t');
                        if any(isnan(timepressure{4})) || any(isnan(timepressure{7}))
                            choice = questdlg('File format not recognized. Pick new time/pressure file or use frame number instead of time?', 'File not recognized', 'Find new file', 'Use frame number', 'Find new file');
                            if strcmp(choice, 'Use frame number')
                                handles.time = 1:handles.l;
                                fileRead = true;
                            end
                        else
                            time = timepressure{4}';
                            handles.rawtime = time(2:end)*1000;
                            handles.time = time - time(2); % first image may correspond to initial image taken before video
                            fileRead = true;
                        end
                    end
                else
                    choice = questdlg('File not chosen. Choose time/pressure file or use frame number instead of time?', 'File not chosen', 'Find time/pressure file', 'Use frame number', 'Find time/pressure file');
                    if strcmp(choice, 'Use frame number')
                        handles.time = 1:handles.l;
                        fileRead = true;
                    end
                end
            end
            % Load existing scale value or find new scale value to convert to microns
            if ~isfield(handles, 'pxScale')
                handles.pxScale = [];
                while isempty(handles.pxScale)
                    choice = questdlg('How do you wish to enter the microns/pixel ratio?','Microns/pixel ratio?','Find existing scale value','Find new scale value','Evaluate in pixels','Find new scale value');
                    switch choice
                        case 'Find existing scale value'
                            [file,path] = uigetfile({'*.mat'},'Choose scale value');
                            if file ~= 0
                                load(fullfile(path,file), 'scale');
                                handles.pxScale = scale;
                                handles.pxScaleUnit = 'µm';
                            end
                        case 'Find new scale value'
                            scale = CreateScale; %Use CreateScale function to load image and measure scale
                            handles.pxScale = scale;
                            [file,path] = uiputfile({'*.mat'},'Save scale value for future use');
                            save(fullfile(path,file),'scale')
                            handles.pxScaleUnit = 'µm';
                        case 'Evaluate in pixels' % if no scale currently available
                            handles.pxScale = 1;
                            handles.pxScaleUnit = 'px';
                    end
                end
            end
            handles.origStartingFrameNum = 1;
            % Initialize notes
            handles.noteTime = [];
            handles.notes = {};
            handles.stringCell = {};
            handles.stringTime = [];
            handles.isArea = [];
            handles.stringFrames = [];
            handles.extraNotes = {};
            handles.MFIAnalysis = {};
            % initialize area fields
            handles.area = [];
            handles.areaFrames = [];
            handles.handDrawn = [];
            handles.areaTime = [];
            handles.areaPolys = {};
            handles.cellPrints = [];
            handles.selected = 1;
            handles.display = true;
            handles.classified = false;
        end
        
        function handles = loadLogFile(app, handles)
            fid = fopen(handles.logFile,'r');
            c = textscan(fid,'%s','Delimiter','\n');
            fclose(fid);
            textCell = c{1};
            line = 1;
            outOfRange = false;
            % find where the notes start
            isNotes = true; %initialize to true, if none found, set to false
            while isempty(strfind(textCell{line}, 'Notes:'))
                line = line + 1;
                if line > length(textCell)
                    isNotes = false;
                    break
                end
            end
            if isNotes
                line = line + 1;
                noteNum = length(handles.notes);
                stringNum = length(handles.stringCell);
                while ~isempty(textCell{line}) %notes continue until empty line
                    if ~isstrprop(textCell{line}(1), 'digit') % first should be a number (time)
                        break
                    end
                    idx = 2;
                    while ~isnan(str2double(textCell{line}(1:idx))) %find where time value ends
                        idx = idx + 1;
                    end
                    noteTime = str2double(textCell{line}(1:idx-1));
                    [tDiff,timeIdx] = min(abs(handles.time - noteTime));
                    if tDiff > .5 % if out of range, do not save
                        outOfRange = true;
                        line = line + 1;
                    else
                        noteNum = noteNum + 1;
                        stringNum = stringNum + 1;
                        handles.noteTime(noteNum) = handles.time(timeIdx);
                        handles.notes{noteNum} = textCell{line}(idx+1:end);
                        handles.stringCell{stringNum} = sprintf('%.3f-%s', handles.noteTime(noteNum), handles.notes{noteNum});
                        handles.stringTime(stringNum) = handles.noteTime(noteNum);
                        handles.isArea(stringNum) = false;
                        handles.stringFrames(stringNum) = timeIdx;
                        line = line + 1;
                    end
                    if line > length(textCell)
                        break
                    end
                end
            end
            line = 1;
            if outOfRange
                uiwait(msgbox('One or more notes fell outside the time range of the current analysis','Note(s) out of range','modal'));
            end
            % Find any "extra notes"
            isExtraNotes = true;
            while isempty(strfind(textCell{line}, 'Extra Notes:')) %scan to find extra notes
                line = line + 1;
                if line > length(textCell)
                    isExtraNotes = false;
                    break
                end
            end
            if isExtraNotes % read in extra notes line by line
                line = line + 1;
                extraNoteNum = length(handles.extraNotes);
                while ~isempty(textCell{line})
                    extraNoteNum = extraNoteNum + 1;
                    handles.extraNotes{extraNoteNum} = textCell{line};
                    line = line + 1;
                    if line > length(textCell)
                        break
                    end
                end
            end
            % Now, find any notes on MFI analysis
            isMFI = true;
            line = 1;
            while isempty(strfind(textCell{line}, 'MFI Analysis:'))
                line = line + 1;
                if line > length(textCell)
                    isMFI = false;
                    break
                end
            end
            if isMFI
                line = line + 1;
                MFINum = length(handles.MFIAnalysis);
                while ~isempty(textCell{line})
                    MFINum = MFINum + 1;
                    handles.MFIAnalysis{MFINum} = textCell{line};
                    line = line + 1;
                    if line > length(textCell)
                        break
                    end
                end
            end
            set(handles.areaList,'Value',1)
            set(handles.areaList,'String',handles.stringCell)
        end
        
        function handles = saveArea(app, xpos, ypos, handDrawn, handles)
            %first, establish saving directory if not chosen yet
            if ~isfield(handles, 'areaFile')
                file = 0;
                while file == 0
                    [file,path] = uiputfile('*.txt', 'Designate file to save area data');
                end
                handles.areaFile = fullfile(path,file);
                analysisFile = strcat('analysisInfo', file);
                handles.analysisInfoFile = fullfile(path,analysisFile);
                cellPrintsFile = strcat('cellPrints', file(1:end-4),'.mat');
                handles.cellPrintsFile = fullfile(path,cellPrintsFile);
            end
            if ~any(isnan(xpos)) %otherwise refreshing files after deleting data
                area = polyarea(xpos,ypos)*(handles.pxScale^2); %convert pixels to area in squared microns
                % save values in handles structure
                L = length(handles.area);
                handles.area(L+1) = area;
                handles.areaFrames(L+1) = handles.frameNum;
                handles.handDrawn(L+1) = handDrawn;
                handles.areaTime(L+1) = handles.time(handles.frameNum);
                handles.areaPolys{L+1} = horzcat(xpos, ypos);
                handles.cellPrints(:,:,L+1) = handles.curPrint;
                N = length(handles.stringCell);
                handles.stringCell{N+1} = sprintf('%.3fs - %.3f%s^2',handles.areaTime(L+1),area,handles.pxScaleUnit);
                handles.stringTime(N+1) = handles.areaTime(L+1);
                handles.isArea(N+1) = true;
                handles.stringFrames(N+1) = handles.frameNum;
                %re-sort handles.stringCell
                [handles.stringTime,index] = sort(handles.stringTime);
                handles.stringCell = handles.stringCell(index);
                handles.isArea = handles.isArea(index);
                handles.stringFrames = handles.stringFrames(index);
                %re-sort areas
                [handles.areaTime,index] = sort(handles.areaTime);
                handles.area = handles.area(index);
                handles.areaFrames = handles.areaFrames(index);
                handles.handDrawn = handles.handDrawn(index);
                handles.areaPolys = handles.areaPolys(index);
                handles.cellPrints = handles.cellPrints(:,:,index);
            else
                try area = handles.area(handles.frameNum);
                catch
                    area = handles.area(1);
                end
            end
            set(handles.areaList,'Value',1)
            set(handles.areaList,'String',handles.stringCell)
            handDrawnIdx = find(handles.handDrawn);
            [path,~,~] = fileparts(handles.areaFile);
            % now, format analysis information into txt file
            printString{1} = sprintf('Saving directory: %s', path);
            printString{2} = sprintf('Image source: %s', cd);
            printString{3} = sprintf('File type: %s', handles.fileType);
            printString{4} = sprintf('Scale value: %.5f microns/pixel', handles.pxScale);
            if handles.cropped
                printString{5} = 'Crop coordinates:';
                for i = 1:length(handles.cropPos(:,1))
                    printString{5+i} = sprintf('%d, %d', handles.cropPos(i,1), handles.cropPos(i,2));
                end
            end
            % list out coordinates for the hand drawn polygons
            for i = 1:length(handDrawnIdx)
                L = length(printString);
                frame = handles.areaFrames(handDrawnIdx(i)) + handles.origStartingFrameNum - 1;
                printString{L+1} = sprintf('Coordinates for frame %d hand-drawn analysis:', frame);
                xCoor = handles.areaPolys{handDrawnIdx(i)}(:,1);
                yCoor = handles.areaPolys{handDrawnIdx(i)}(:,2);
                printString{L+2} = sprintf('%.2f, %.2f\r\n', [xCoor'; yCoor']);
            end
            if any(~handles.handDrawn) % if there was an automated analysis, list analyzed frames and parameter settings
                L = length(printString);
                printString{L+1} = 'Automated analysis for following frames:';
                printString{L+2} = sprintf('%d\r\n', handles.areaFrames(~handles.handDrawn)+handles.origStartingFrameNum-1);
                printString{L+3} = 'Automated Analysis Settings:';
                if ~handles.classified
                    printString{L+4} = sprintf('Ilow = %d', str2double(get(handles.lowVal, 'string')));
                    printString{L+5} = sprintf('Ihigh = %d', str2double(get(handles.highVal, 'string')));
                    printString{L+6} = sprintf('StdLim = %.2f', str2double(get(handles.stdVal, 'string')));
                    printString{L+7} = sprintf('Blur = %d', str2double(get(handles.blur, 'string')));
                    printString{L+8} = sprintf('MinSize = %d', str2double(get(handles.minSize, 'string')));
                else
                    printString{L+4} = sprintf('Images sent to pixel classifier located at %s', handles.ilastikProject);
                    printString{L+5} = sprintf('Blur = %d', str2double(get(handles.blur, 'string')));
                    printString{L+6} = sprintf('MinSize = %d', str2double(get(handles.minSize, 'string')));
                end
            end
            % re-save area file
            fileID = fopen(handles.areaFile, 'w');
            fprintf(fileID, '%.4f %.4f\r\n', [handles.areaTime; handles.area]);
            fclose(fileID);
            % save "analysis info" file
            fileID = fopen(handles.analysisInfoFile, 'w');
            fprintf(fileID, '%s\r\n', printString{:});
            fclose(fileID);
            % save "cellPrints" file
            cellPrints = ~handles.cellPrints; %so the cell contour is black
            save(handles.cellPrintsFile, 'cellPrints');
            if handles.display % plot on graph if handles.display is true
                axes(handles.areaGraph)
                plot(handles.areaTime, handles.area)
                if max(handles.area) < 300
                    ylim([0 300])
                end
                xlabel('Time (s)')
                ylabel(sprintf('Area (%s)', handles.pxScaleUnit))
                hold on
                plot(handles.time(handles.frameNum), area, 'Marker', '*', 'MarkerEdgeColor', 'r', 'MarkerSize', 6)
                hold off
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function cellAreaRICM_OpeningFcn(app, varargin)
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to cellAreaRICM (see VARARGIN)
            
            % Choose default command line output for cellAreaRICM
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes cellAreaRICM wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            if length(varargin) == 2
                if isfield(varargin{1}, 'BG')
                    handles.BGFile = varargin{1}.BGFile;
                    handles.BG = varargin{1}.BG;
                    handles.subtracted = true;
                end
                handles.mainPath = varargin{1}.mainPath;
                handles.fileType = varargin{1}.fileType;
                handles.pxScale = varargin{1}.pxScale;
                handles.pxScaleUnit = varargin{1}.pxScaleUnit;
            end
            handles = initializeSeq(app, handles);
            if length(varargin) == 1 % for instance, when called by graphMaxPixelsGUI
                handles.logFile = varargin{1}.logFile;
                handles = loadLogFile(app, handles);
            end
            guidata(hObject,handles);
        end

        % Value changed function: areaList
        function areaList_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to areaList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns areaList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from areaList
            handles.selected = get(hObject,'Value');
            handles.frameNum = handles.stringFrames(handles.selected);
            % navigate to the image selected
            set(handles.videoSlider,'Value',(handles.frameNum/length(handles.files)))
            text = sprintf('Frame#%d (%.2fs)', handles.frameNum+handles.origStartingFrameNum - 1, handles.time(handles.frameNum));
            set(handles.frameText, 'String', text)
            handles.currentImage = double(imread(handles.files{handles.frameNum}));
            if handles.subtracted % subtract background if background subtraction has been selected
                handles.currentImage = (handles.currentImage - mean(handles.currentImage(:))) - handles.BG;
            end
            handles.currentImage = polyCrop(handles.currentImage,handles.cropPos);
            axes(handles.cellVideo)
            imagesc(handles.currentImage)
            daspect([1 1 1])
            if handles.isArea(handles.selected) %if the list item is an area data point, display shape
                areaIdx = 0;
                for i = 1:handles.selected
                    if handles.isArea(i)
                        areaIdx = areaIdx + 1;
                    end
                end
                position = handles.areaPolys{areaIdx};
                xpos = position(:,1);
                ypos = position(:,2);
                handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none'); %draw the pre-analyzed shape
                axes(handles.areaGraph)
                plot(handles.areaTime, handles.area)
                if max(handles.area) < 300
                    ylim([0 300])
                end
                xlabel('Time (s)')
                ylabel(sprintf('Area (%s)', handles.pxScaleUnit))
                hold on
                plot(handles.time(handles.frameNum), handles.area(areaIdx), 'Marker', '*', 'MarkerEdgeColor', 'r', 'MarkerSize', 6)
                hold off
            end
            guidata(hObject,handles)
        end

        % Value changed function: blur
        function blur_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to blur (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of blur as text
            %        str2double(get(hObject,'String')) returns contents of blur as a double
            if get(handles.preview, 'Value')
                try
                    set(handles.patch,'Visible','off')
                catch %no action
                end
                handles = automatedAnalysis(app, handles, true, false);
            end
            guidata(hObject, handles)
        end

        % Button pushed function: crop
        function crop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to crop (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isempty(handles.area) % need to crop before starting analysis, because traces are saved in cropped frame
                axes(handles.cellVideo)
                handles.currentImage = double(imread(handles.files{handles.frameNum}));
                if handles.subtracted
                    handles.currentImage = handles.currentImage - mean(handles.currentImage(:)) - handles.BG;
                end
                imagesc(handles.currentImage)
                daspect([1 1 1])
                choice = questdlg('Rectangular crop or polygonal crop?','Type of crop','Rectangular','Polygonal','Rectangular');
                switch choice
                    case 'Rectangular'
                        [handles.currentImage,handles.rect] = imcrop;
                        x0 = handles.rect(1);
                        y0 = handles.rect(2);
                        x1 = x0 + handles.rect(3);
                        y1 = y0 + handles.rect(4);
                        handles.cropPos = [x0,y0; x0,y1; x1,y1; x1,y0];
                    case 'Polygonal'
                        try cropPoly = drawpolygon;
                            handles.cropPos = cropPoly.Position;
                        catch
                            cropPoly = impoly;
                            apiPoly = iptgetapi(cropPoly);
                            handles.cropPos = apiPoly.getPosition;
                        end
                        handles.currentImage = polyCrop(handles.currentImage,handles.cropPos);
                end
                imagesc(handles.currentImage)
                daspect([1 1 1])
                handles.cropped = true;
            else
                choice = questdlg('Analysis has already started. Do you wish to clear the current area data and then crop?', 'Clear data?', 'Yes', 'No', 'No');
                switch choice
                    case 'Yes' %need to delete area data and restart
                        handles.area = [];
                        handles.areaFrames = [];
                        handles.handDrawn = [];
                        handles.areaTime = [];
                        handles.areaPolys = {};
                        handles.cellPrints = [];
                        % keep notes in list, not areas
                        handles.stringCell = handles.stringCell(~handles.isArea);
                        handles.stringTime = handles.stringTime(~handles.isArea);
                        handles.stringFrames = handles.stringFrames(~handles.isArea);
                        handles.isArea = handles.isArea(~handles.isArea);
                        set(handles.areaList,'Value',1)
                        set(handles.areaList,'String',handles.stringCell)
                        guidata(hObject,handles)
                end
            end
            guidata(hObject,handles)
        end

        % Button pushed function: deleteMult
        function deleteMult_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to deleteMult (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            choice = questdlg('Would you like to delete one every few frames or delete a full section?'...
                ,'Delete which frames?','Every few','Section','Every few');
            if strcmp(choice,'Every few')
                multCount = inputdlg('Keep one frame out of every how many?');
                multCount = str2num(multCount{1});
                L = length(handles.stringCell);
                index = 1:multCount:L;
                if index(end) ~= L
                    index = horzcat(index,L);
                end
                notAreaIdx = find(~handles.isArea);
                areaIdx = find(handles.isArea);
                keepAreaIdx = areaIdx(index);
                stringIdx = sort(horzcat(notAreaIdx,keepAreaIdx));
            elseif strcmp(choice,'Section')
                firstLast = {'first','last'};
                selectRegion = zeros(1,2);
                for i = 1:2
                    uiwait(msgbox(sprintf('Select %s in region you want to delete, then click out and press enter',firstLast{i})))
                    % next section does not proceed until user presses enter
                    f=gcf;
                    val='0';
                    while ~strcmp(val,'return')
                        logic = waitforbuttonpress;
                        if logic
                            val=get(f,'CurrentKey');
                        end
                    end
                    selectRegion(i) = get(handles.areaList,'Value');
                end
                notAreaIdx = find(~handles.isArea);
                areaIdx = find(handles.isArea);
                selectRegionIdx(1) = find(areaIdx == selectRegion(1));
                selectRegionIdx(2) = find(areaIdx == selectRegion(2));
                endIdx = max(length(handles.area),selectRegionIdx(2));
                index = [1:selectRegionIdx(1)-1,selectRegionIdx(2)+1:endIdx];
                endString = max(length(handles.isArea),selectRegion(2));
                stringIdx = [1:selectRegion(1)-1,selectRegion(2)+1:endString];
            end
            handles.area = handles.area(index);
            handles.handDrawn = handles.handDrawn(index);
            handles.areaFrames = handles.areaFrames(index);
            handles.areaTime = handles.areaTime(index);
            handles.areaPolys = handles.areaPolys(index);
            handles.cellPrints = handles.cellPrints(:,:,index);
            if handles.classified
                handles.ilastikKeep(handles.frameNum) = false;
            end
            
            handles.isArea = handles.isArea(stringIdx);
            handles.stringTime = handles.stringTime(stringIdx);
            handles.stringFrames = handles.stringFrames(stringIdx);
            handles.stringCell = handles.stringCell(stringIdx);
            set(handles.areaList,'Value',1)
            set(handles.areaList,'String',handles.stringCell)
            try
                set(handles.patch,'Visible','off')
            catch %take no action
            end
            axes(handles.areaGraph)
            plot(handles.areaTime, handles.area)
            if max(handles.area) < 300
                ylim([0 300])
            end
            xlabel('Time (s)')
            ylabel(sprintf('Area (%s)', handles.pxScaleUnit))
            handles = saveArea(app, NaN, NaN, NaN, handles);
            guidata(hObject,handles)
        end

        % Button pushed function: deletePoint
        function deletePoint_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to deletePoint (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if ~isempty(handles.stringCell)
                l = length(handles.stringCell);
                index = horzcat(1:(handles.selected-1),(handles.selected+1):l);
                if handles.isArea(handles.selected)
                    areaSelect = sum(handles.isArea(1:handles.selected));
                    areaIdx = horzcat(1:(areaSelect-1),(areaSelect+1):length(handles.area));
                    handles.area = handles.area(areaIdx);
                    handles.handDrawn = handles.handDrawn(areaIdx);
                    handles.areaFrames = handles.areaFrames(areaIdx);
                    handles.areaTime = handles.areaTime(areaIdx);
                    handles.areaPolys = handles.areaPolys(areaIdx);
                    handles.cellPrints = handles.cellPrints(:,:,areaIdx);
                    if handles.classified
                        handles.ilastikKeep(handles.frameNum) = false;
                    end
                end
                handles.isArea = handles.isArea(index);
                handles.stringTime = handles.stringTime(index);
                handles.stringFrames = handles.stringFrames(index);
                handles.stringCell = handles.stringCell(index);
                set(handles.areaList,'Value',1)
                set(handles.areaList,'String',handles.stringCell)
                try
                    set(handles.patch,'Visible','off')
                catch %take no action
                end
                axes(handles.areaGraph)
                plot(handles.areaTime, handles.area)
                if max(handles.area) < 300
                    ylim([0 300])
                end
                xlabel('Time (s)')
                ylabel(sprintf('Area (%s)', handles.pxScaleUnit))
                handles = saveArea(app, NaN, NaN, NaN, handles);
            else
                msgbox('No areas or notes to delete')
            end
            guidata(hObject,handles)
        end

        % Value changed function: highVal
        function highVal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to highVal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of highVal as text
            %        str2double(get(hObject,'String')) returns contents of highVal as a double
            if get(handles.preview, 'Value') && ~handles.classified
                try
                    set(handles.patch,'Visible','off')
                catch %no action
                end
                handles = automatedAnalysis(app, handles, true, false);
            end
            guidata(hObject, handles)
        end

        % Value changed function: lowVal
        function lowVal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to lowVal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of lowVal as text
            %        str2double(get(hObject,'String')) returns contents of lowVal as a double
            if get(handles.preview, 'Value') && ~handles.classified
                try
                    set(handles.patch,'Visible','off')
                catch %no action
                end
                handles = automatedAnalysis(app, handles, true, false);
            end
            guidata(hObject, handles)
        end

        % Value changed function: minSize
        function minSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to minSize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of minSize as text
            %        str2double(get(hObject,'String')) returns contents of minSize as a double
            if get(handles.preview, 'Value')
                try
                    set(handles.patch,'Visible','off')
                catch %no action
                end
                handles = automatedAnalysis(app, handles, true, false);
            end
            guidata(hObject, handles)
        end

        % Callback function
        function pixelClassifier_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pixelClassifier (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if get(hObject, 'Value')
                set(handles.simpleThresholdBox, 'HighlightColor', [0 0 0])
                set(handles.simpleThresholdBox, 'BackgroundColor', [0.5 0.5 0.5])
                set(handles.pixelClassifierBox, 'HighlightColor', [1 1 0])
                set(handles.pixelClassifierBox, 'BackgroundColor', [0.94 0.94 0.94])
                set(handles.preview, 'Value', 0)
            %     choice = questdlg('Open ilastik to apply/build classifier?', 'Open ilastik?', 'Yes', 'No, load pre-analyzed', 'Yes');
                uiwait(msgbox('Select folder to save pre-processed images', 'Select saving folder', 'modal'))
                path = uigetdir;
                fid = fopen(fullfile(path, '_timesonly.txt'), 'w');
                timesFileData = handles.rawtime([1, handles.origStartingFrameNum:(handles.origStartingFrameNum+length(handles.files)-1)]);
                fprintf(fid, '%.3f\r\n', timesFileData);
                fclose(fid);
                newFiles = cell(1, length(handles.files));
                for i = 1:length(handles.files)
                    im = double(imread(handles.files{i}));
                    if handles.subtracted % subtract background if background subtraction has been selected
                        im = (im - mean(im(:))) - handles.BG;
                    end
                    im = polyCrop(im,handles.cropPos);
                    im = uint8(255 * mat2gray(im));
                    newFiles{i} = fullfile(path, sprintf('new%s', handles.files{i}));
                    newFiles{i} = strrep(newFiles{i}, '\', '\\'); % for system command later
                    imwrite(im, newFiles{i})
                end
                uiwait(msgbox('Perform analysis in ilastik, then press okay when finished','modal'))
                %             system('C:\Program Files\ilastik-1.3.0\ilastik.exe');
                [file, path] = uigetfile('*.ilp', 'Where was the ilastik project saved?');
                handles.ilastikProject = fullfile(path, file);
                [~, handles.ilastikPath] = uigetfile('*.bmp', 'Get first output image from ilastik');
                seq = dir(fullfile(handles.ilastikPath, '*_Simple Segmentation.bmp'));
                handles.ilastikFiles = {seq.name};
                handles.ilastikKeep = true(1, length(handles.ilastikFiles));
                for i = 1:length(handles.ilastikFiles)
                    im = imread(fullfile(handles.ilastikPath,handles.ilastikFiles{i}));
                    imLogic = im == 1;
                    minSize = str2double(get(handles.minSize, 'String'));
                    blur = str2double(get(handles.blur, 'String'));
                    boundary = tracesFromBinary(imLogic, minSize, blur);
                    xpos = boundary(:,2);
                    ypos = boundary(:,1);
                    handles.areaPolys{i} = horzcat(xpos, ypos);
                    handles.frameNum = i;
                    handles.classified = true;
                    handles = saveArea(app, xpos, ypos, false, handles);
                end
            else
                set(handles.simpleThresholdBox, 'HighlightColor', [1 1 0])
                set(handles.simpleThresholdBox, 'BackgroundColor', [0.94 0.94 0.94])
                set(handles.pixelClassifierBox, 'HighlightColor', [0 0 0])
                set(handles.pixelClassifierBox, 'BackgroundColor', [0.5 0.5 0.5])
                handles.classified = false;
            end
            guidata(hObject, handles)
        end

        % Value changed function: preview
        function preview_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to preview (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of preview
            logic = handles.areaFrames == handles.frameNum;
            if get(hObject, 'Value')
                handles = automatedAnalysis(app, handles, true, false);
            elseif any(logic)
                axes(handles.cellVideo)
                try
                    set(handles.patch,'Visible','off')
                catch %no action
                end
                position = handles.areaPolys{logic};
                xpos = position(:,1);
                ypos = position(:,2);
                handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none'); %draw the pre-analyzed shape
                axes(handles.areaGraph)
                plot(handles.areaTime, handles.area)
                if max(handles.area) < 300
                    ylim([0 300])
                end
                xlabel('Time (s)')
                ylabel(sprintf('Area (%s)', handles.pxScaleUnit))
                hold on
                plot(handles.time(handles.frameNum), handles.area(logic), 'Marker', '*', 'MarkerEdgeColor', 'r', 'MarkerSize', 6)
                hold off
            end
            guidata(hObject, handles)
        end

        % Button pushed function: processAll
        function processAll_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to processAll (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            N = length(handles.files);
            multCount = inputdlg('Keep one frame out of every how many?');
            multCount = str2num(multCount{1});
            analyzeAll = false;
            overwriteNone = false;
            set(handles.preview, 'Value', true)
            h = waitbar(0,'Wait for data to process', 'windowstyle', 'modal');
            % analyze all frames, but determine whether to replace hand-drawn areas
            % update: modified to only start automated analysis after the initial hand
            % drawn areas
            if isfield(handles,'areaFrames')
                lastHandDrawn = find(handles.handDrawn,1,'last');
                if isempty(lastHandDrawn)
                    startIdx = 1;
                else
                    startIdx = handles.areaFrames(lastHandDrawn) + 1;
                end
            else
                startIdx = 1;
            end
            for i = startIdx:multCount:N
                handles.frameNum = i;
                set(handles.videoSlider,'Value',(handles.frameNum/length(handles.files)))
                handles.currentImage = double(imread(handles.files{handles.frameNum}));
                if handles.subtracted % subtract background if background subtraction has been selected
                    handles.currentImage = (handles.currentImage - mean(handles.currentImage(:))) - handles.BG;
                end
                handles.currentImage = polyCrop(handles.currentImage,handles.cropPos);
                logic = handles.areaFrames == i;
                if any(logic) && handles.handDrawn(logic) && ~analyzeAll
                    if overwriteNone
                        continue;
                    end
                    axes(handles.cellVideo) %#ok<LAXES>
                    imagesc(handles.currentImage)
                    daspect([1 1 1])
                    position = handles.areaPolys{logic};
                    xpos = position(:,1);
                    ypos = position(:,2);
                    handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none'); %show the hand-drawn shape
                    % now, preview automated analysis (will display in red)
                    handles = automatedAnalysis(app, handles, true, false);
                    choice = questdlg('Overwrite this hand-drawn area (hand-drawn in yellow, automated in red)? (exit dialog to not overwrite)', 'Overwrite hand-drawn?', 'Overwrite current', 'Overwrite all', 'Overwrite none', 'Overwrite current');
                    switch choice
                        case 'Overwrite all'
                            analyzeAll = true;
                            handles = automatedAnalysis(app, handles, false, true);
                        case 'Overwrite current'
                            handles = automatedAnalysis(app, handles, false, true);
                        case 'Overwrite none'
                            overwriteNone = true;
                            continue;
                        case 'Do not overwrite'
                            continue;
                    end
                elseif i ~= N
                    handles = automatedAnalysis(app, handles, false, true); % no display
                else
                    set(handles.preview, 'Value', false)
                    axes(handles.cellVideo) %#ok<LAXES>
                    imagesc(handles.currentImage)
                    daspect([1 1 1])
                    handles = automatedAnalysis(app, handles, true, true); % display final data
                end
                waitbar(i/N)
            end
            close(h)
            guidata(hObject, handles)
        end

        % Button pushed function: saveData
        function saveData_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to saveData (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %first, save a picture of the initial frame with the area of interest boxed
            startingFrame = double(imread(handles.files{1}));
            if handles.subtracted
                startingFrame = startingFrame - mean(startingFrame(:)) - handles.BG;
            end
            figure()
            imagesc(startingFrame)
            daspect([1 1 1])
            colormap gray
            xpos = handles.cropPos(:,1);
            ypos = handles.cropPos(:,2);
            patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none');
            startingFrameBoxed = frame2im(getframe(gcf));
            close(gcf)
            line = 0;
            stringFile = {};
            if ~isempty(handles.notes)
                line = line + 1;
                stringFile{line} = 'Notes:';
                line = line + 1;
                stringFile{line} = sprintf('%s\r\n', handles.stringCell{~handles.isArea});
            end
            addNotes = inputdlg('Enter any additional notes','Additional notes');
            if ~isempty(handles.extraNotes)
                l = length(handles.extraNotes);
                handles.extraNotes{l+1} = addNotes{:};
            else
                handles.extraNotes = addNotes;
            end
            if ~isempty(handles.extraNotes)
                line = line + 1;
                stringFile{line} = 'Extra Notes:';
                line = line + 1;
                stringFile{line} = sprintf('%s\r\n', handles.extraNotes{:});
            end
            if ~isempty(handles.MFIAnalysis)
                line = line + 1;
                stringFile{line} = 'MFI Analysis:';
                line = line + 1;
                stringFile{line} = sprintf('%s\r\n', handles.MFIAnalysis{:});
            end
            if ~isempty(stringFile)
                if ~isfield(handles, 'logFile')
                    file = 0;
                    while file == 0
                        [file,path] = uiputfile('*.txt','Save log file as');
                    end
                    handles.logFile = fullfile(path,file);
                end
                fid = fopen(handles.logFile, 'w');
                fprintf(fid, '%s\r\n', stringFile{:});
                fclose(fid);
                [path,~,~] = fileparts(handles.logFile);
            end
            file = horzcat('cellLocationRICM.',handles.fileType);
            imwrite(startingFrameBoxed, fullfile(path,file));
            if isfield(handles, 'BGFile')
                [~,~,ext] = fileparts(handles.BGFile);
                saveBG = strcat('BG', ext);
                copyfile(handles.BGFile, fullfile(path, saveBG));
            end
%             choice = questdlg('Do you wish to restart or close?','Restart/Close','Restart','Close','Close');
%             switch choice
%                 case 'Restart'
%                     choice2 = questdlg('Keep the same directory, background picture, and scale value?', 'Keep source?', 'Yes', 'No', 'Yes');
%                     if strcmp(choice2,'Yes')
%                         delete(gcf)
%                         cellAreaRICM_App(handles,[])
%                     else
%                         delete(gcf)
%                         cellAreaRICM
%                     end
%                 case 'Close'
%                     delete(gcf)
%                 case 'MFI Analysis'
%                     delete(gcf)
%                     logFile = handles.logFile;
%                     timeVals = handles.rawtime;
%                     graphMaxPixelsGUI(logFile, timeVals)
%             end
        end

        % Button pushed function: setEnd
        function setEnd_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setEnd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles, 'stringCell')
                if any(handles.stringFrames > handles.frameNum) %keep all stored data points and notes in sequence range
                    msgbox('Cannot set end here. Setting end at final note or data point')
                    handles.frameNum = max(handles.stringFrames);
                end
            end
            handles.time = handles.time(1:handles.frameNum);
            handles.files = handles.files(1:handles.frameNum);
            handles.l = length(handles.files);
            set(handles.videoSlider,'SliderStep',[1/(handles.l-1) 10/(handles.l-1)]);
            set(handles.videoSlider,'Value',1);
            text = sprintf('Frame#%d (%.2fs)', handles.frameNum+handles.origStartingFrameNum - 1, handles.time(handles.frameNum));
            set(handles.frameText, 'String', text)
            guidata(hObject,handles);
        end

        % Button pushed function: setStart
        function setStart_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to setStart (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            if isfield(handles, 'stringFrames')
                if any(handles.stringFrames < handles.frameNum)
                    msgbox('Cannot set start here. Setting start at first note or data point')
                    handles.frameNum = min(handles.stringFrames);
                end
                handles.stringFrames = handles.stringFrames - handles.frameNum + 1;
                if isfield(handles, 'areaFrames')
                    handles.areaFrames = handles.areaFrames - handles.frameNum + 1;
                end
                if handles.classified
                    handles.ilastikFiles = handles.ilastikFiles(handles.frameNum:end);
                end
            end
            handles.time = handles.time(handles.frameNum:end);
            handles.files = handles.files(handles.frameNum:end);
            handles.origStartingFrameNum = handles.frameNum + handles.origStartingFrameNum - 1; %frame number in terms of original sequence
            handles.frameNum = 1;
            handles.l = length(handles.files);
            set(handles.videoSlider,'SliderStep',[1/(handles.l-1) 10/(handles.l-1)]);
            set(handles.videoSlider,'Value',0);
            % string still displays frame number in terms of original seq
            text = sprintf('Frame#%d (%.2fs)', handles.frameNum+handles.origStartingFrameNum - 1, handles.time(handles.frameNum));
            set(handles.frameText, 'String', text)
            guidata(hObject,handles);
        end

        % Value changed function: stdVal
        function stdVal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to stdVal (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of stdVal as text
            %        str2double(get(hObject,'String')) returns contents of stdVal as a double
            if get(handles.preview, 'Value') && ~handles.classified
                try
                    set(handles.patch,'Visible','off')
                catch %no action
                end
                handles = automatedAnalysis(app, handles, true, false);
            end
            guidata(hObject, handles)
        end

        % Button pushed function: subBackground
        function subBackground_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to subBackground (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.currentImage = double(imread(handles.files{handles.frameNum}));
            if handles.subtracted
                choice = questdlg('Do you wish to redo the background subtraction?','Redo?','Yes','No, proceed','Yes');
                switch choice
                    case 'Yes'
                        handles.subtracted = false;
                end
            end
            if ~handles.subtracted % no background subtracted yet
                [BgName, BgPath] = uigetfile({'*.*'},'Select background image');
                BG = double(imread(fullfile(BgPath,BgName)));
                BG = wiener2(BG,[3,3]); % low pass Wiener filter to remove noise
                handles.BG = BG - mean(BG(:)); % deviations from  mean
                handles.currentImage = handles.currentImage - mean(handles.currentImage(:)) - handles.BG;
                handles.subtracted = true;
                handles.currentImage = polyCrop(handles.currentImage,handles.cropPos);
                axes(handles.cellVideo)
                imagesc(handles.currentImage);
                daspect([1 1 1])
                handles.BGFile = fullfile(BgPath, BgName);
            end
            guidata(hObject,handles)
        end

        % Value changed function: videoSlider
        function videoSlider_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to videoSlider (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'Value') returns position of slider
            %        get(hObject,'Min') and get(hObject,'Max') to determine range of slider
            position = get(hObject,'Value');
            % find frame number based on current position of slider
            handles.frameNum = round(position * (handles.l-1))+1;
            text = sprintf('Frame#%d (%.2fs)', handles.frameNum+handles.origStartingFrameNum - 1, handles.time(handles.frameNum));
            set(handles.frameText, 'String', text) % to have a readout of where in the video you are
            handles.currentImage = double(imread(handles.files{handles.frameNum}));
            if handles.subtracted % subtract background if background subtraction has been selected
                handles.currentImage = (handles.currentImage - mean(handles.currentImage(:))) - handles.BG;
            end
            handles.currentImage = polyCrop(handles.currentImage,handles.cropPos);
            axes(handles.cellVideo)
            imagesc(handles.currentImage)
            daspect([1 1 1])
            logic = handles.areaFrames == handles.frameNum;
            if get(handles.preview, 'Value')
                handles = automatedAnalysis(app, handles, true, false);
                if any(logic)
                    handles.selected = find(logic) + sum(handles.noteTime < handles.time(handles.frameNum));
                    set(handles.areaList,'Value', handles.selected)
                end
            elseif any(logic)
                position = handles.areaPolys{logic};
                xpos = position(:,1);
                ypos = position(:,2);
                handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none'); %draw the pre-analyzed shape
                axes(handles.areaGraph)
                plot(handles.areaTime, handles.area)
                if max(handles.area) < 300
                    ylim([0 300])
                end
                xlabel('Time (s)')
                ylabel(sprintf('Area (%s^2)', handles.pxScaleUnit))
                hold on
                plot(handles.time(handles.frameNum), handles.area(logic), 'Marker', '*', 'MarkerEdgeColor', 'r', 'MarkerSize', 6)
                hold off
                handles.selected = find(logic) + sum(handles.noteTime < handles.time(handles.frameNum));
                set(handles.areaList,'Value', handles.selected)
            end
            guidata(hObject, handles)
%             videoSlider_Callback(app, event)
        end

        % Button pushed function: rightFrame
        function rightFrameButtonPushed(app, event)
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            handles.frameNum = handles.frameNum + 1;
%             oldVal = (handles.frameNum-2)/(handles.l-1);
            if handles.frameNum <= handles.l
                newVal = (handles.frameNum-1)/(handles.l-1);
                set(handles.videoSlider,'Value',newVal)
            else
                handles.frameNum = handles.l;
                newVal = 1;
                set(handles.videoSlider,'Value',newVal)
            end
            handles.frameNum = round(newVal * (handles.l-1))+1;
            text = sprintf('Frame#%d (%.2fs)', handles.frameNum+handles.origStartingFrameNum - 1, handles.time(handles.frameNum));
            set(handles.frameText, 'String', text) % to have a readout of where in the video you are
            handles.currentImage = double(imread(handles.files{handles.frameNum}));
            if handles.subtracted % subtract background if background subtraction has been selected
                handles.currentImage = (handles.currentImage - mean(handles.currentImage(:))) - handles.BG;
            end
            handles.currentImage = polyCrop(handles.currentImage,handles.cropPos);
            axes(handles.cellVideo)
            imagesc(handles.currentImage)
            daspect([1 1 1])
            logic = handles.areaFrames == handles.frameNum;
            if get(handles.preview, 'Value')
                handles = automatedAnalysis(app, handles, true, false);
                if any(logic)
                    handles.selected = find(logic) + sum(handles.noteTime < handles.time(handles.frameNum));
                    set(handles.areaList,'Value', handles.selected)
                end
            elseif any(logic)
                position = handles.areaPolys{logic};
                xpos = position(:,1);
                ypos = position(:,2);
                handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none'); %draw the pre-analyzed shape
                axes(handles.areaGraph)
                plot(handles.areaTime, handles.area)
                if max(handles.area) < 300
                    ylim([0 300])
                end
                xlabel('Time (s)')
                ylabel(sprintf('Area (%s^2)', handles.pxScaleUnit))
                hold on
                plot(handles.time(handles.frameNum), handles.area(logic), 'Marker', '*', 'MarkerEdgeColor', 'r', 'MarkerSize', 6)
                hold off
                handles.selected = find(logic) + sum(handles.noteTime < handles.time(handles.frameNum));
                set(handles.areaList,'Value', handles.selected)
            end
            guidata(hObject, handles)
        end

        % Button pushed function: leftFrame
        function leftFrameButtonPushed(app, event)
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event);
            handles.frameNum = handles.frameNum - 1;
            if handles.frameNum >= 1
                newVal = (handles.frameNum-1)/(handles.l-1);
                set(handles.videoSlider,'Value',newVal)
            else
                handles.frameNum = 1;
                newVal = 0;
                set(handles.videoSlider,'Value',newVal)
            end
            handles.frameNum = round(newVal * (handles.l-1))+1;
            text = sprintf('Frame#%d (%.2fs)', handles.frameNum+handles.origStartingFrameNum - 1, handles.time(handles.frameNum));
            set(handles.frameText, 'String', text) % to have a readout of where in the video you are
            handles.currentImage = double(imread(handles.files{handles.frameNum}));
            if handles.subtracted % subtract background if background subtraction has been selected
                handles.currentImage = (handles.currentImage - mean(handles.currentImage(:))) - handles.BG;
            end
            handles.currentImage = polyCrop(handles.currentImage,handles.cropPos);
            axes(handles.cellVideo)
            imagesc(handles.currentImage)
            daspect([1 1 1])
            logic = handles.areaFrames == handles.frameNum;
            if get(handles.preview, 'Value')
                handles = automatedAnalysis(app, handles, true, false);
                if any(logic)
                    handles.selected = find(logic) + sum(handles.noteTime < handles.time(handles.frameNum));
                    set(handles.areaList,'Value', handles.selected)
                end
            elseif any(logic)
                position = handles.areaPolys{logic};
                xpos = position(:,1);
                ypos = position(:,2);
                handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none'); %draw the pre-analyzed shape
                axes(handles.areaGraph)
                plot(handles.areaTime, handles.area)
                if max(handles.area) < 300
                    ylim([0 300])
                end
                xlabel('Time (s)')
                ylabel(sprintf('Area (%s)', handles.pxScaleUnit))
                hold on
                plot(handles.time(handles.frameNum), handles.area(logic), 'Marker', '*', 'MarkerEdgeColor', 'r', 'MarkerSize', 6)
                hold off
                handles.selected = find(logic) + sum(handles.noteTime < handles.time(handles.frameNum));
                set(handles.areaList,'Value', handles.selected)
            end
            guidata(hObject, handles)
        end

        % Value changed function: analyzeByHand
        function analyzeByHandValueChanged(app, event)
%             value = app.analyzeByHand.Value;
            [hObject, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            axes(handles.cellVideo)
            handles.display = true;
            if get(hObject,'Value') == 1
                set(hObject,'String','In Progress')
                set(hObject,'BackgroundColor',[0.8, 0.8, 0.8])
                if any(handles.areaFrames == handles.frameNum) %if this frame has already been analyzed
                    num = find(handles.areaFrames == handles.frameNum);
                    if size(handles.areaPolys{num},1) < 150
                        h = impoly(gca, handles.areaPolys{num}); %redraw areaPoly drawn earlier
                    else
                        h = impoly(gca, handles.areaPolys{num}(1:10:end, :)); % display one every 5 pts for prior automated
                    end
                    L = length(handles.area);
                    index = horzcat(1:(num-1),(num+1):L);
                    % get rid of the past analysis for this frame (this is a redo)
                    handles.area = handles.area(index);
                    handles.areaFrames = handles.areaFrames(index);
                    handles.areaTime = handles.areaTime(index);
                    handles.areaPolys = handles.areaPolys(index);
                    handles.cellPrints = handles.cellPrints(:,:,index);
                    % find position of this area in the string list (to delete)
                    testIdx = 1;
                    while sum(handles.isArea(1:testIdx)) < num
                        testIdx = testIdx + 1;
                    end
                    N = length(handles.stringCell);
                    index2 = horzcat(1:(testIdx-1),(testIdx+1):N);
                    handles.stringCell = handles.stringCell(index2);
                    handles.stringFrames = handles.stringFrames(index2);
                    handles.isArea = handles.isArea(index2);
                    handles.stringTime = handles.stringTime(index2);
                    % if there is a leftover visible 'patch', make invisible
                    try
                        set(handles.patch,'Visible','off')
                    catch
                        % if fails, take no action
                    end
                    % reset area list strings to all excluding current being analyzed
                    set(handles.areaList,'Value',1)
                    set(handles.areaList,'String',handles.stringCell)
                    guidata(hObject,handles)
                else
                    h = impoly(gca); %this frame has not been analyzed before, user draws shape
                end
                % next section does not proceed until user presses enter
                f=gcf;
                val='0';
                while ~strcmp(val,'return')
                    logic = waitforbuttonpress;
                    if logic
                        val=get(f,'CurrentKey');
                    end
                end
                % get data from drawn polygon
                api = iptgetapi(h);
                position = api.getPosition;
                xpos = position(:,1);
                ypos = position(:,2);
                set(h, 'visible', 'off')
                axes(handles.cellVideo)
                hold on
                handles.patch = patch('XData',xpos,'YData',ypos,'EdgeColor','yellow','FaceColor','none');
                hold off
                edgePixels = boundaryFromVertices(ypos,xpos); %flipped to transfer to image
                curPrint = false(size(handles.currentImage));
                for i = 1:size(edgePixels,1)
                    curPrint(edgePixels(i,1),edgePixels(i,2)) = true;
                end
                handles.curPrint = imfill(curPrint, 'holes');
                handles = saveArea(app, xpos, ypos, true, handles);

                % re-set button
                set(hObject,'String','Analyze By Hand')
                set(hObject,'BackgroundColor',[0.93, 0.93, 0.93])
                set(hObject,'Value',0)
            else
                msgbox('Analysis in progress');
            end
            guidata(hObject,handles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'cellAreaRICM';
            app.figure1.Tag = 'figure1';
            app.cellVideo = uiaxes(app.figure1);
            app.cellVideo.XTick = [];
            app.cellVideo.XTickLabel = '';
            app.cellVideo.YTick = [];
            app.cellVideo.Tag = 'cellVideo';
            app.videoSlider = uislider(app.figure1);
            app.videoSlider.Limits = [0 1];
            app.videoSlider.MajorTicks = [];
            app.videoSlider.ValueChangedFcn = createCallbackFcn(app, @videoSlider_Callback, true);
            app.videoSlider.MinorTicks = [];
            app.videoSlider.Tag = 'videoSlider';
            app.setStart = uibutton(app.figure1, 'push');
            app.setStart.ButtonPushedFcn = createCallbackFcn(app, @setStart_Callback, true);
            app.setStart.Tag = 'setStart';
            app.setStart.Text = 'Set as Start';
            app.setEnd = uibutton(app.figure1, 'push');
            app.setEnd.ButtonPushedFcn = createCallbackFcn(app, @setEnd_Callback, true);
            app.setEnd.Tag = 'setEnd';
            app.setEnd.Text = 'Set as End';
            app.uibuttongroup2 = uibuttongroup(app.figure1);
            app.uibuttongroup2.Title = 'Image Prep';
            app.uibuttongroup2.Tag = 'uibuttongroup2';
            app.subBackground = uibutton(app.uibuttongroup2, 'push');
            app.subBackground.ButtonPushedFcn = createCallbackFcn(app, @subBackground_Callback, true);
            app.subBackground.Tag = 'subBackground';
            app.subBackground.Text = 'Subtract Background';
            app.crop = uibutton(app.uibuttongroup2, 'push');
            app.crop.ButtonPushedFcn = createCallbackFcn(app, @crop_Callback, true);
            app.crop.Tag = 'crop';
            app.crop.Text = 'Crop';
            app.text10_2 = uilabel(app.uibuttongroup2);
            app.text10_2.Tag = 'text10';
            app.text10_2.Text = 'press this button, draw polygon, and then press enter';
            app.analyzeByHand = uibutton(app.uibuttongroup2, 'state');
            app.analyzeByHand.ValueChangedFcn = createCallbackFcn(app, @analyzeByHandValueChanged, true);
            app.analyzeByHand.Tag = 'analyzeByHand';
            app.analyzeByHand.Text = 'Analyze by hand';
            app.uibuttongroup3 = uibuttongroup(app.figure1);
            app.uibuttongroup3.Title = 'Data Display';
            app.uibuttongroup3.Tag = 'uibuttongroup3';
            app.areaGraph = uiaxes(app.uibuttongroup3);
            app.areaGraph.Tag = 'areaGraph';
            app.areaList = uilistbox(app.uibuttongroup3);
            app.areaList.Items = {};
            app.areaList.ValueChangedFcn = createCallbackFcn(app, @areaList_Callback, true);
            app.areaList.Tag = 'areaList';
            app.areaList.Value = {};
            app.deletePoint = uibutton(app.uibuttongroup3, 'push');
            app.deletePoint.ButtonPushedFcn = createCallbackFcn(app, @deletePoint_Callback, true);
            app.deletePoint.Tag = 'deletePoint';
            app.deletePoint.Text = 'Delete Selected Point';
            app.deleteMult = uibutton(app.uibuttongroup3, 'push');
            app.deleteMult.ButtonPushedFcn = createCallbackFcn(app, @deleteMult_Callback, true);
            app.deleteMult.Tag = 'deleteMult';
            app.deleteMult.Text = 'Delete Mult Points';
            app.saveData = uibutton(app.figure1, 'push');
            app.saveData.ButtonPushedFcn = createCallbackFcn(app, @saveData_Callback, true);
            app.saveData.Tag = 'saveData';
            app.saveData.Text = 'Save Data';
            app.frameText = uilabel(app.figure1);
            app.frameText.Tag = 'frameText';
            app.frameText.Text = 'Frame # (#s)';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Automated Analysis Settings';
            app.uipanel1.Tag = 'uipanel1';
            app.preview = uicheckbox(app.uipanel1);
            app.preview.ValueChangedFcn = createCallbackFcn(app, @preview_Callback, true);
            app.preview.Tag = 'preview';
            app.preview.Text = {'Preview'; 'outline'};
            app.simpleThresholdBox = uibuttongroup(app.uipanel1);
            app.simpleThresholdBox.Tag = 'simpleThresholdBox';
            app.text19 = uilabel(app.simpleThresholdBox);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Settings:';
            app.highVal = uieditfield(app.simpleThresholdBox, 'text');
            app.highVal.ValueChangedFcn = createCallbackFcn(app, @highVal_Callback, true);
            app.highVal.Tag = 'highVal';
            app.highVal.Value = 'Inf';
            app.stdVal = uieditfield(app.simpleThresholdBox, 'text');
            app.stdVal.ValueChangedFcn = createCallbackFcn(app, @stdVal_Callback, true);
            app.stdVal.Tag = 'stdVal';
            app.stdVal.Value = 'Inf';
            app.text11 = uilabel(app.simpleThresholdBox);
            app.text11.Tag = 'text11';
            app.text11.Text = 'or greater than';
            app.text14 = uilabel(app.simpleThresholdBox);
            app.text14.Tag = 'text14';
            app.text14.Text = {'or std dev'; 'greater than'};
            app.lowVal = uieditfield(app.simpleThresholdBox, 'text');
            app.lowVal.ValueChangedFcn = createCallbackFcn(app, @lowVal_Callback, true);
            app.lowVal.Tag = 'lowVal';
            app.lowVal.Value = '-Inf';
            app.text10 = uilabel(app.simpleThresholdBox);
            app.text10.Tag = 'text10';
            app.text10.Text = {'Intensity '; 'less than'};
            app.blur = uieditfield(app.uipanel1, 'text');
            app.blur.ValueChangedFcn = createCallbackFcn(app, @blur_Callback, true);
            app.blur.Tag = 'blur';
            app.blur.Value = '3';
            app.minSize = uieditfield(app.uipanel1, 'text');
            app.minSize.ValueChangedFcn = createCallbackFcn(app, @minSize_Callback, true);
            app.minSize.Tag = 'minSize';
            app.minSize.Value = '30';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Blur:';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Minimum Size (in pixels):';
            app.processAll = uibutton(app.uipanel1, 'push');
            app.processAll.ButtonPushedFcn = createCallbackFcn(app, @processAll_Callback, true);
            app.processAll.Tag = 'processAll';
            app.processAll.Text = 'Apply Settings';
            app.leftFrame = uibutton(app.figure1, 'push');
            app.leftFrame.ButtonPushedFcn = createCallbackFcn(app, @leftFrameButtonPushed, true);
            app.leftFrame.Text = '←';
            app.rightFrame = uibutton(app.figure1, 'push');
            app.rightFrame.ButtonPushedFcn = createCallbackFcn(app, @rightFrameButtonPushed, true);
            app.rightFrame.Text = '→';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellAreaRICM_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)cellAreaRICM_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end